classdef Decoder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        DecodeButton                   matlab.ui.control.Button
        SucessLabel                    matlab.ui.control.Label
        SucessEditField                matlab.ui.control.NumericEditField
        TotalWatermarksEditFieldLabel  matlab.ui.control.Label
        TotalWatermarksEditField       matlab.ui.control.NumericEditField
        TotalidentifiedEditFieldLabel  matlab.ui.control.Label
        TotalidentifiedEditField       matlab.ui.control.NumericEditField
        DoneLampLabel                  matlab.ui.control.Label
        DoneLamp                       matlab.ui.control.Lamp
        ActualTimesEditFieldLabel      matlab.ui.control.Label
        ActualTimesEditField           matlab.ui.control.NumericEditField
        ChordEditFieldLabel            matlab.ui.control.Label
        ChordEditField                 matlab.ui.control.EditField
        DECODERSSWLabel                matlab.ui.control.Label
        FeaturesLabel                  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
        %get info that should be sent via blueooth and save it in the corresponding folder of the app
        load('decoder.mat','n','alpha','Lf','answer','bestMatchID','idsong');  
        chords=['C' 'D' 'E' 'F' 'G' 'A' 'B'];%our prototype database chord ,we select a random chord for it
        %get audios 
        dir = 'songs'; 
        filename = strcat(dir,filesep,answer);
        [orig,fs]=audioread(filename);
        play_block=audioplayer(orig,fs);
        origw=audioread('audio_watermarked.wav');
        M=floor(length(orig)/Lf);%obtain the number of block M
        idx=1;%it express a watermark every idx blocks 
        watermark=zeros(M,1);%initialize the array to store the watermak bits
        play(play_block);% the song begins until a warning event occurs
        
        for k=0:idx:M %every 0.1 seconds add a watermark and add the end skip 
        init=(Lf*k)+1;
        fin=init+Lf-1;
        if(fin<=length(orig))
        origf=orig(init:fin);%get the block
        origwf=origw(init:fin);%get the block of the watermarked audio
        
        %calculate the DCT of the block
        dct_vector= dct(origf); 
        dct_vector_w=dct(origwf);
        %organize by power the spectrum so the highest components(more robust to noise) ill have the
        %watermarking
        [~, index] = max(abs(dct_vector));
        [~, indexw] = max(abs(dct_vector_w));
        % get the watermark
       wa = (dct_vector_w(indexw) - dct_vector(index))/alpha;
       %verify threshold cirterion to evaluate detection
      if(wa>-0.016 && wa<0.016) %still needs adjustement in maxium-minium values
          stop(play_block);
           msgbox('Warning:The song comes from unexpected source');  
           closereq;
      else
          wa=sign(wa);
      end
      %Show the additional features
       if((wa==1) || (wa==-1))
           %compute the actual time and a chord
           app.ActualTimesEditField.Value=k*(Lf/fs);
           app.ChordEditField.Value=chords(randi(length(chords)));
           % pause the programm execution to synchronize better with the actual audio
           pause('on');
           pause(Lf/fs);
           pause('off');
       end
       watermark(k+1)=wa;%save the current watermark
        else
            stop(play_block);%if this is not true stop the audio indicating strange audio file
             
        break;
        end
        end
        app.DoneLamp.Color='g';%when the decoding is done the lamp ll light green
        %Process to evaluate the accuracy
        Successvalue=1;
        Successsamples=0;
        %compare the watermark stream obtained with the idsong (original watermark sequence of specified song)
        for j=1:length(watermark)
                correctw=watermark(j);
                bit=mod(j,length(idsong));
                if(bit==0)
                    bit=length(idsong);
                end
              Successvalue=Successvalue&(correctw==idsong(bit)); 
            if(Successvalue)
                Successsamples=Successsamples+1;
            end
            Successvalue=1;
        end
        %compute the percentage of accuracy and if is below this level it's a possible false copy.
        Percentage=(Successsamples/M )*100;
        if (Percentage<50)
         msgbox('Warning:The song comes from unexpected source');  
         
        end
        
        %To display the calculated values
        Totalwt=length(watermark);
        app.SucessEditField.Value=Percentage;
        app.TotalWatermarksEditField.Value=Totalwt;
        app.TotalidentifiedEditField.Value=Successsamples;
        
        %plot of the watermark sequence
        endin=n*5;%first five idsong stream to analyze behaviour
        t=1:endin;
        stem(app.UIAxes,t,watermark(1:endin));


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Obtained Watermark stream')
            xlabel(app.UIAxes, 'Bit block [n]')
            ylabel(app.UIAxes, 'Value')
            app.UIAxes.XLim = [0 Inf];
            app.UIAxes.YLim = [-1 1];
            app.DecodeButton = uibutton(app.UIFigure, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            app.SucessLabel = uilabel(app.UIFigure);
            app.SucessLabel.Text = 'Sucess [%]';
            app.SucessEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalWatermarksEditFieldLabel = uilabel(app.UIFigure);
            app.TotalWatermarksEditFieldLabel.Text = 'Total Watermarks';
            app.TotalWatermarksEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalidentifiedEditFieldLabel = uilabel(app.UIFigure);
            app.TotalidentifiedEditFieldLabel.Text = 'Total identified';
            app.TotalidentifiedEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoneLampLabel = uilabel(app.UIFigure);
            app.DoneLampLabel.Text = 'Done';
            app.DoneLamp = uilamp(app.UIFigure);
            app.ActualTimesEditFieldLabel = uilabel(app.UIFigure);
            app.ActualTimesEditFieldLabel.Text = 'Actual Time [s]';
            app.ActualTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChordEditFieldLabel = uilabel(app.UIFigure);
            app.ChordEditFieldLabel.Text = 'Chord';
            app.ChordEditField = uieditfield(app.UIFigure, 'text');
            app.DECODERSSWLabel = uilabel(app.UIFigure);
            app.DECODERSSWLabel.Text = 'DECODER SSW';
            app.FeaturesLabel = uilabel(app.UIFigure);
            app.FeaturesLabel.Text = 'Features';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Decoder

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end