classdef Fingerprinting_1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreateDatabaseButton            matlab.ui.control.Button
        SongDropDownLabel               matlab.ui.control.Label
        SongDropDown                    matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        FromButtonGroup                 matlab.ui.container.ButtonGroup
        MicrophoneButton                matlab.ui.control.RadioButton
        RandomSegmentButton             matlab.ui.control.RadioButton
        IsthissongknownLampLabel        matlab.ui.control.Label
        IsthissongknownLamp             matlab.ui.control.Lamp
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        NameofthesongEditFieldLabel     matlab.ui.control.Label
        NameofthesongEditField          matlab.ui.control.EditField
        RecordClipButton                matlab.ui.control.Button
        ProceedtoembbedwatermarkButton  matlab.ui.control.Button
        SNRdBSliderLabel                matlab.ui.control.Label
        SNRdBSlider                     matlab.ui.control.Slider
        DurationClipsButtonGroup        matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        ForrandomSegmentLabel           matlab.ui.control.Label
        ConstellationmapsLabel          matlab.ui.control.Label
        ClipHistrogramwithDropDownLabel  matlab.ui.control.Label
        ClipHistrogramwithDropDown      matlab.ui.control.DropDown
        OfSongEditFieldLabel            matlab.ui.control.Label
        OfSongEditField                 matlab.ui.control.EditField
        AudioFingerprintingIdentificationLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        Nmatches % Description
    end


    methods (Access = public)
    
          function [magS,F,T,peaks] = fingerprint(~,sound, fs)
% This function takes a sound and sampling frequency.  It returns a binary
% matrix indicating the locations of peaks in the spectrogram.
%
%inputs:
%sound:corresponding song or clip expressed in stereo format
%fs: samppling frequency of the song/clip. Tipically 44.1KHz
%
%outputs:
%magS: magnitude of the FFT spectogram analysis
%F: Frequencies considered in the FFT analysis ,units Hz
%T: Time considered in the spectogram analysis 

new_smpl_rate = 8000; % sampling rate
time_res = .064; % for spectrogram
gs = 3; % grid size for spectrogram peak search
desiredPPS = 30; % scales the threshold
% 
 y=sound;%for make data base
% INSERT PREPROCESING CODE HERE
% [y,fs] = audioread(sound);%for tests 
y=mean(y,2);
y=y-mean(y);
y=resample(y,new_smpl_rate,fs);
% Create the spectrogram
% Because the signal is real, only positive frequencies will be returned by
% the spectrogram function, which is all we will need.

window=64;
noverlap=32;
[S,F,T]=spectrogram(y,window,noverlap,[],new_smpl_rate);

% INSERT SPECTROGRAM CODE HERE.
%magS = 0; % Remove and replace this line to create a magS that is the magnitute of the spectrogram
magS=abs(S);


% Find the local peaks with respect to the nearest gs entries in both
% directions


% INSERT PEAK FINDING CODE HERE.
peaks = ones(size(S)); % 2D boolean array indicating position of local peaks
for horShift = -gs:gs
    for vertShift = -gs:gs
        if(vertShift ~= 0 || horShift ~= 0) % Avoid comparing to self
            % peaks = peaks.* (INSERT CODE HERE);
            CS = circshift(magS, [horShift,vertShift]);
            P=(magS>CS);
            peaks=peaks&P;
        end
    end
end
% 
% 
% % EXPERIMENT WITH DIFFERENT THRESHOLD VALUES HERE.
 %threshold = 1.05;
% 
% % Calculate threshold to use.
% % We will set one threshold for the entire segment.  Improvements might be
% % possible by adapting the threshold throughout the length of the segment,
% % and setting a lower threshold for higher frequencies.
% 
  peakMags = peaks.*magS; 
  %ATTEMPT OF ADAPTATIVE THRESHOLD -changes depending of the time evolution of the sound
%   res= min(factor(size(peakMags,2)));%resolution for adaptative threshold 
   res=23;
   cont=0;
   k=1;
   %i'll take part of the peaksMags matrix acocrding to the resolution
   %given
   %obtain all the peaks eliminating all the zeros and express it as a
   %peaks array
   %i calculate the geometric mean to this array and that ll be my threshold
   %i ll apply this threshold specific to the part of peakMags into
   %consideration
   %this result junk the matrix i changed it by the one in the
   %respective position it was taken from.
   for t=1:size(peakMags,2)
       cont=cont+1;
       c=cont*res;
        
      %when i arrive to the last part of the matrix so we donn't have
      %errors of index out of dimensions
            if(c>size(peakMags,2))
             c=size(peakMags,2);
             peaksth1=peakMags(:,k:c);
             sortedpeakMags = sort(peaksth1(:),'descend');
             sortedpeakMags=sortedpeakMags(sortedpeakMags ~= 0);
             threshold=geomean(sortedpeakMags);
             peaksth1 = (peaksth1 >= threshold);
             peaks(:,k:c)=peaksth1;
             break;
            else      
       peakth=peakMags(:,k:c);
      sortedpeakMags = sort(peakth(:),'descend');
       sortedpeakMags=sortedpeakMags(sortedpeakMags ~= 0);
        threshold=geomean(sortedpeakMags);
             peaksth = (peakth >= threshold);
             peaks(:,k:c)=peaksth;
            end
     k=k+res; 
  end


          end
        
       function tuple = convert_to_pairs(~,peaks)
%
% This code takes a binary matrix as input and returns a list of proximate
% peaks.  There are three parameters in the code to change the range of the
% search and the number of pairs allowed per peak.
%


del_t = 35; % bound on time difference (in pixels)
del_f = 30; % bound on frequency difference (in pixels)
fanout = 3; % Maximum number of pairs per peak.


[f, t] = find(peaks);
peakCount = length(f);

tuple = zeros(fanout*peakCount, 4);
index = 1;


for i = 1:peakCount
    links = 0;
    for f2 = min(size(peaks,1),f(i)+1):min(size(peaks,1),f(i)+del_f)
        if peaks(f2,t(i))
            tuple(index, :) = [t(i) t(i) f(i) f2];
            links = links + 1;
            index = index + 1;
        end
        if (links >= fanout)
            break;
        end
    end
    for t2 = min(size(peaks,2),t(i)+1):min(size(peaks,2),t(i)+del_t)
        if (links >= fanout)
            break;
        end
        for f2 = max(1,f(i)-del_f):min(size(peaks,1),f(i)+del_f)
            if (links >= fanout)
                break;
            end
            if peaks(f2,t2)
                tuple(index, :) = [t(i) t2 f(i) f2];
                links = links + 1;
                index = index + 1;
            end
        end
    end
end

tuple = tuple(1:(index - 1), :);

    end

     function maxCollisions = add_to_table(~,tuple, songidnum)
%
% Pass to this function a list of proximate pairs and the song ID number of
% the song that the pairs were taken from.
% 
% This function changes a global variable:  hashtable.  The tuples are
% appended to the hashtable.  The return value is not crucial, only for
% information.

global hashtable;

hashTableSize = size(hashtable,1);


% Count the max number of collisions for a given hash (FYI)
maxCollisions = 0;

        
for m = 1:size(tuple, 1)
    hash = simple_hash(app,tuple(m,3),tuple(m,4), tuple(m,2)-tuple(m,1), hashTableSize);
    %  first instance of this hash
    if isempty(hashtable{hash,1})
        hashtable{hash, 1} = songidnum; % # id of the song
        hashtable{hash, 2} = tuple(m,1); 
    % duplicate instance of this hash
    else
        hashtable{hash, 1} = [hashtable{hash, 1}, songidnum];
        hashtable{hash, 2} = [hashtable{hash, 2}, tuple(m,1)];

        collisions = length(hashtable{hash, 1});
        if collisions > maxCollisions
            maxCollisions = collisions;
        end
    end
end

     end
        
       function songList = getMp3List(~,direc)
%
% This function returns a list of filenames that contain '.mp3' in the
% specified directory.

    dirObject = dir(direc);

    fullList = cell(size(dirObject));
    for i=1:size(dirObject,1)
        fullList{i} = dirObject(i).name;
    end
    
    
    songList = cell(size(fullList));
    counter=1;
    for ii = 1:size(fullList,1)
        if ~isempty(strfind(fullList{ii},'.mp3'))
           songList{counter} = fullList{ii};
           counter = counter+1;
        end
    end
    if counter==1
        disp 'No MP3s Found'; 
    end
    
    % Reduce list to correct length
    songList = songList(1:counter-1);
       end
       
        function hash = simple_hash(~,f1, f2, deltaT, size)
%function hash = simple_hash(f1, f2, deltaT, size)
%
%  Hash function: produces index to a hash table
%
% This is just intended to be a chaotic function with roughly a uniform
% distribution over the range.

hash = mod(round( size*1000000*(log(abs(f1)+2) + 2*log(abs(f2)+2) + 3*log(abs(deltaT)+2)) ), size) + 1;

        end
        
    end %end public methods
    %%private


    methods (Access = private)

        % Button pushed function: CreateDatabaseButton
        function CreateDatabaseButtonPushed(app, event)
             
             
    dir = 'songs'; % This is the folder that the MP3 files must be placed in.
    songs = getMp3List(app,dir);

    hashTableSize = 100000; % This can be adjusted.  Setting it too small will cause more accidental collisions.

    global hashtable

% Check if we have a database in the workspace
if ~exist('songid')
    % Load database if one exists
    if exist('SONGID.mat')
        load('SONGID.mat','songid');
        load('HASHTABLE.mat','hashtable');
    else  
        % Create new database
        songid = cell(0);
        hashtable = cell(hashTableSize,2); % 
    end
end


songIndex = length(songid); % This becomes the song ID number.

% Add songs to songid and fingerprints to hashtable
someNewSongs = 0;
for i = 1:length(songs)
    
    % Check if the song is already in the database
    songFound = 0;
    for m = 1:length(songid)
        if strcmp(songs{i}, songid{m})
            songFound = 1;
            break;
        end
    end
    
    if ~songFound
        someNewSongs = 1;
        songIndex = songIndex + 1;
        filename = strcat(dir, filesep, songs{i});
        [sound,fs] = audioread(filename);
        
        %create the fingerprint and the tuples to create the database
        [~,peaks]=fingerprint(app,sound,fs);
        tuple=convert_to_pairs(app,peaks);
        add_to_table(app,tuple,songIndex);
        songid{songIndex,1} = songs{i};
    end
end
app.SongDropDown.Items =songid ;
app.SongDropDown.ItemsData = songid ;
global numSongs
numSongs = songIndex;
if someNewSongs
    save('SONGID.mat', 'songid');
    save('HASHTABLE.mat', 'hashtable');
end


        end

        % Button pushed function: ProceedtoembbedwatermarkButton
        function ProceedtoembbedwatermarkButtonPushed(app, event)
           
            appdesigner('Encoder') ;
        end

        % Value changed function: SongDropDown
        function SongDropDownValueChanged(app, event)
            %plot the corresponding constellation map of each song
           
            value = app.SongDropDown.Value;
           filename = strcat('songs', filesep,value);
        [sound,fs] = audioread(filename);
        [plot_magS,plot_F,plot_T,plot_peaks]=fingerprint(app,sound,fs);
        
        plot_logS = log(plot_magS);
        peaksSpec = (plot_logS - min(min(plot_logS))).*(1-plot_peaks);
        imagesc(app.UIAxes,plot_T,plot_F,peaksSpec);
        colormap(app.UIAxes,1-gray);


        end

        % Button pushed function: RecordClipButton
        function RecordClipButtonPushed(app, event)
            duration = str2double(app.Button.Text);
        load SONGID.mat songid
        load HASHTABLE.mat hashtable;

        numSongs = length(songid);


         if app.MicrophoneButton.Value %Record the external sound 
        % Settings used for recording.
        fs = 44100; % Sample frequency
        bits = 16;  % Bits used per sample
    
        % Record audio for <duration> seconds.
        recObj = audiorecorder(fs, bits, 2);
        handle1 = msgbox('Recording');
        recordblocking(recObj, duration);
        delete(handle1)
    
        % Store data in Double-precision array.
        sound = getaudiodata(recObj);
    
        elseif app.RandomSegmentButton.Value% Select a random segment
            
            add_noise = 1; % Optionally add noise by making this 1.
            SNRdB = app.SNRdBSlider.Value; % Signal-to-noise Ratio in dB, if noise is added.  Can be negative.
            
            dir = 'songs'; % This is the folder that the MP3 files are in.
            songs = getMp3List(app,dir);
            
            % Select random song
            thisSongIndex = ceil(length(songs)*rand);
            app.OfSongEditField.Value=songs{thisSongIndex};
            filename = strcat(dir, filesep, songs{thisSongIndex});
            [sound,fs] = audioread(filename);
            sound = mean(sound,2);
            sound = sound - mean(sound);
            
            % Select random segment
            if length(sound) > ceil(duration*fs)
                shiftRange = length(sound) - ceil(duration*fs)+1;
                shift = ceil(shiftRange*rand);
                sound = sound(shift:shift+ceil(duration*fs)-1);
            end
            
            % Add noise
            if add_noise
                soundPower = mean(sound.^2);
                noise = randn(size(sound))*sqrt(soundPower/10^(SNRdB/10));
                sound = sound + noise;
            end
        else
            disp('Error in selection');
        end
        
        %get the fingerprint and tuples of the clip
        [magSc,Fc,Tc,peaks_clip]=fingerprint(app,sound,fs);
        clipTuples=convert_to_pairs(app,peaks_clip);
        
        
        hashTableSize = size(hashtable,1);


% Construct the cell of matches
matches = cell(numSongs,1);
for k = 1:size(clipTuples, 1)
    %  CALCULATE HASH
%     clipHash = 1;   % Replace this line
    clipHash=simple_hash(app,clipTuples(k,3),clipTuples(k,4), clipTuples(k,2)-clipTuples(k,1), hashTableSize);
    % If an entry exists with this hash, find the song(s) with matching peak pairs
     if (~isempty(hashtable{clipHash, 1}))
         matchID = hashtable{clipHash, 1}; % row vector of collisions
         matchTime = hashtable{clipHash, 2}; % row vector of collisions
%         
%         % Calculate the time difference between clip pair and song pair
%         % INSERT CODE HERE
         Time_offset= matchTime-clipTuples(k,1);
%                 
%         % Add matches to the lists for each individual song
        for n = 1:numSongs
              matches{n} = [matches{n},Time_offset(find(matchID==n))];
         end
    end
end

% % Find the counts of the mode of the time offset array for each song
 M=zeros(numSongs,1);
 F=zeros(numSongs,1);
 for k = 1:numSongs
%     % Calculate the mode and frequency for each song
     [M(k),F(k)]=mode(matches{k});
%  
 end
 %Criterion of matching Id 
 % %The idea might be a threshold of mode , if is it's enough certain number
%so to express confidence.
% Other and in use ll be calculate the variance between the frequencies if it's too
%high means one song has more ocurrencies than the others
 if((var(F)>100))%value to tune
 Fmax=max(F);
    bestMatchID=find(F==Fmax);

    confidence=1;
 else
     bestMatchID=0;
     confidence=0;
 end
 

if(bestMatchID == 0)
     answer='No soung found in the database';
     app.IsthissongknownLamp.Color='r';
     app.NameofthesongEditField.Value=answer;
else   
    %save variables for enconding
answer = songid{bestMatchID};   
app.ProceedtoembbedwatermarkButton.Enable='on';
app.IsthissongknownLamp.Color='g';
app.NameofthesongEditField.Value=answer;
 end

 save('answer.mat', 'answer','bestMatchID');    
  app.Nmatches=matches;
  %%plot constellation map current clip
        plot_logSc = log(magSc);
        peaksSpec = (plot_logSc - min(min(plot_logSc))).*(1-peaks_clip);
        imagesc(app.UIAxes2,Tc,Fc,peaksSpec);
        colormap(app.UIAxes2,1-gray); 
         y=zeros(1,length(app.Nmatches));
            for i=1:length(matches)
                y(i)=i;
            end
            %define the options for the histogram
            app.ClipHistrogramwithDropDown.Items=songid;
            app.ClipHistrogramwithDropDown.ItemsData=y;


        end

        % Value changed function: ClipHistrogramwithDropDown
        function ClipHistrogramwithDropDownValueChanged(app, event)
              %get the corresponding match with song and plot its histogram
            value = app.ClipHistrogramwithDropDown.Value;
             histogram(app.UIAxes3,app.Nmatches{value},1000);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CreateDatabaseButton = uibutton(app.UIFigure, 'push');
            app.CreateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDatabaseButtonPushed, true);
            app.CreateDatabaseButton.Text = 'Create Database';
            app.SongDropDownLabel = uilabel(app.UIFigure);
            app.SongDropDownLabel.Text = 'Song';
            app.SongDropDown = uidropdown(app.UIFigure);
            app.SongDropDown.Items = {' '};
            app.SongDropDown.Editable = 'on';
            app.SongDropDown.ValueChangedFcn = createCallbackFcn(app, @SongDropDownValueChanged, true);
            app.SongDropDown.Value = ' ';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Constellation map Database Song')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Frequency [Hz]')
            app.UIAxes.XLim = [0 Inf];
            app.UIAxes.YLim = [-Inf Inf];
            app.UIAxes.YDir = 'reverse';
            app.FromButtonGroup = uibuttongroup(app.UIFigure);
            app.FromButtonGroup.Title = 'From';
            app.MicrophoneButton = uiradiobutton(app.FromButtonGroup);
            app.MicrophoneButton.Text = 'Microphone';
            app.RandomSegmentButton = uiradiobutton(app.FromButtonGroup);
            app.RandomSegmentButton.Text = 'Random Segment';
            app.RandomSegmentButton.Value = true;
            app.IsthissongknownLampLabel = uilabel(app.UIFigure);
            app.IsthissongknownLampLabel.Text = 'Is this song known?';
            app.IsthissongknownLamp = uilamp(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Constellation Clip Constellation Map')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Frequency [Hz]')
            app.UIAxes2.XLim = [0 Inf];
            app.UIAxes2.YLim = [0 Inf];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Histogram of offests ')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.XLim = [-Inf Inf];
            app.UIAxes3.YLim = [0 Inf];
            app.NameofthesongEditFieldLabel = uilabel(app.UIFigure);
            app.NameofthesongEditFieldLabel.Text = 'Name of the song';
            app.NameofthesongEditField = uieditfield(app.UIFigure, 'text');
            app.RecordClipButton = uibutton(app.UIFigure, 'push');
            app.RecordClipButton.ButtonPushedFcn = createCallbackFcn(app, @RecordClipButtonPushed, true);
            app.RecordClipButton.Text = 'Record Clip';
            app.ProceedtoembbedwatermarkButton = uibutton(app.UIFigure, 'push');
            app.ProceedtoembbedwatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoembbedwatermarkButtonPushed, true);
            app.ProceedtoembbedwatermarkButton.Enable = 'off';
            app.ProceedtoembbedwatermarkButton.Text = 'Proceed to embbed watermark ';
            app.SNRdBSliderLabel = uilabel(app.UIFigure);
            app.SNRdBSliderLabel.Text = 'SNR [dB]';
            app.SNRdBSlider = uislider(app.UIFigure);
            app.SNRdBSlider.Limits = [0 15];
            app.DurationClipsButtonGroup = uibuttongroup(app.UIFigure);
            app.DurationClipsButtonGroup.Title = 'Duration Clip [s]';
            app.Button = uiradiobutton(app.DurationClipsButtonGroup);
            app.Button.Text = '5';
            app.Button_2 = uiradiobutton(app.DurationClipsButtonGroup);
            app.Button_2.Text = '10';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.DurationClipsButtonGroup);
            app.Button_3.Text = '15';
            app.ForrandomSegmentLabel = uilabel(app.UIFigure);
            app.ForrandomSegmentLabel.Text = 'For random Segment';
            app.ConstellationmapsLabel = uilabel(app.UIFigure);
            app.ConstellationmapsLabel.Text = 'Constellation maps';
            app.ClipHistrogramwithDropDownLabel = uilabel(app.UIFigure);
            app.ClipHistrogramwithDropDownLabel.Text = 'Clip Histrogram with:';
            app.ClipHistrogramwithDropDown = uidropdown(app.UIFigure);
            app.ClipHistrogramwithDropDown.Items = {'[]'};
            app.ClipHistrogramwithDropDown.ValueChangedFcn = createCallbackFcn(app, @ClipHistrogramwithDropDownValueChanged, true);
            app.ClipHistrogramwithDropDown.Value = '[]';
            app.OfSongEditFieldLabel = uilabel(app.UIFigure);
            app.OfSongEditFieldLabel.Text = 'Of Song:';
            app.OfSongEditField = uieditfield(app.UIFigure, 'text');
            app.AudioFingerprintingIdentificationLabel = uilabel(app.UIFigure);
            app.AudioFingerprintingIdentificationLabel.Text = 'Audio Fingerprinting Identification';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fingerprinting_1_0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end