classdef Encoder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        EncoderSSWLabel                matlab.ui.control.Label
        WatermarkAudioButton           matlab.ui.control.Button
        CreateSendAudioWatermarkedButton  matlab.ui.control.Button
        FrameSizesEditFieldLabel       matlab.ui.control.Label
        FrameSizesEditField            matlab.ui.control.EditField
        WatermarkFactorEditFieldLabel  matlab.ui.control.Label
        WatermarkFactorEditField       matlab.ui.control.EditField
        bitswatermarkEditFieldLabel    matlab.ui.control.Label
        bitswatermarkEditField         matlab.ui.control.EditField
        DoneLampLabel                  matlab.ui.control.Label
        DoneLamp                       matlab.ui.control.Lamp
        WithWatermarkCheckBox          matlab.ui.control.CheckBox
        ListenButtonGroup              matlab.ui.container.ButtonGroup
        OriginalButton                 matlab.ui.control.RadioButton
        WatermarkedButton              matlab.ui.control.RadioButton
        PlayButton                     matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ResumeButton                   matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        SongwatermarkedEditFieldLabel  matlab.ui.control.Label
        SongwatermarkedEditField       matlab.ui.control.EditField
    end


    properties (Access = public)
        %these variables are for store values that the decoding process ll use or to display in the app
        origwa % watermarked audio
        origs % original audio
        playeror % audio player object instance for play original audio
        playerwa % audio player object instance for play watermarked audio
        fs1 % sample frequency of the audio
        idofsong % watermark sequence to encode
        N1 % Duration in seconds of each block-window
        alpha1 % Strength of the watermark 
        n1 % #bits of watermark sequence
    end

    methods (Access = public)
    
        function waterseq=GenerateGaussianSequence(~,key,n)
            %this function takes a corresponding key to generate a unique password 
            %for create a sequence of {-1,1} of n bits
            %input: key- a certain number associated with each song that ll give us a dterministic password
                   %n-number of bits of the watermark sequence
            %output: n
            pass = sum(double(key).*(1:length(key)));
            %if n isnt specified by default ll be 1000
            if (nargin == 0)
                n = 1000;
            end
            %store the password as a seed so the value can be replicate every time you give the corresponding seed
            rng(pass);
            %generate the watermark sequence with a gaussian uniform distribution and sign function
            waterseq = sign(randn(n,1));


        end
        
    end


    methods (Access = private)

        % Button pushed function: WatermarkAudioButton
        function WatermarkAudioButtonPushed(app, event)
           %load data given by the audio fingerprinting process
            load('answer.mat','answer','bestMatchID');
            %find the corresponding song and load it
          dir = 'songs'; 
          filename = strcat(dir,filesep,answer);
          [orig,fs]=audioread(filename);
          %read from the fields in the encoder app the corresponding parameters of the encoder
          n=str2double(app.bitswatermarkEditField.Value);%number of bits an positive integer value 
          N=str2double(app.FrameSizesEditField.Value);%durartion in seconds of each block to encode the watermark
          alpha=str2double(app.WatermarkFactorEditField.Value);%the attenuation factor this express a trade off 
          %betwwen robustness-security vs visibility
          idsong=GenerateGaussianSequence(app,bestMatchID,n);%the watermark sequence to the corresponding sequence 
          %that can be only generarting if you know the key, in this case is simple corresponding to the index of the song in the database


          Lf=N*fs;%length of the block expressed in samples 
          M=floor(length(orig)/(Lf));%number of blocks to embbed according to the length of the song, must be positive integer
          origw=orig;%this copy is used to embed each watermark per block and improve performance by already have allocation to store it.
          idx=1;%express how many blocks we embbed a watermark ,e.g if idx=10 each 10 blcoks we ll embed a bit of the sequence
           
          cont=1;
for k=0:idx:M %every 0.1 seconds add a watermark bit to the highest power
    
    init=(Lf*k)+1;
    fin=init+Lf-1;
    if(fin<=length(orig))
    origf=orig(init:fin);%get the block
    %get the current block for the watermark
    watermark=idsong;%store the sequence for use in the loop
    %the embedding of the sequence is done circularly once we embedd the last bit we go back to the first in the sequence 
    bit=mod(cont,length(watermark));
    if(bit==0)
        bit=length(watermark);
        cont=1;
    end

    %calculate the DCT of the block
    dct_vector= dct(origf);    
    %get the maxium energy component of the dct transform in the block ,this should be the less sensible to noise
    [~,index]=max(abs(dct_vector));
    %insert the watermark in the block
    dct_vector(index) = dct_vector(index) + (watermark(bit) * alpha);
    %go back to time domain to get the block 
    water=idct(dct_vector);
%    %add this to the new audio
     origw(init:fin)=water;
     cont=cont+1;
    else
        break;
    end
    app.DoneLamp.Color='g';%when it ends show the lamp done as green
end
    %create audioplayer objects for play/pause/resume/stop the original and watermarked song in the encoder app
    player_orig=audioplayer(orig,fs);
    player_w=audioplayer(origw,fs);

   %store variables when the button of save the audio watermark is pushed and call its event
   app.origwa=origw;
   app.origs=orig;
   app.playeror=player_orig;
   app.playerwa=player_w;
   app.fs1=fs;
   app.idofsong=idsong;
   app.N1=N;
   app.alpha1=alpha;
   app.n1=n;
   
   
            %plot the watermark sequence
            endin=length(origw)-1;
            t=0:endin;

          
             plot(app.UIAxes,t,orig,'b');
              legend(app.UIAxes,'original song');
              stem(app.UIAxes2,1:length(idsong),idsong);
             %mention the song that was watermarked
             app.SongwatermarkedEditField.Value=answer;


        end

        % Callback function
        function NameofthesongEditFieldValueChanged(app, event)


        end

        % Button pushed function: CreateSendAudioWatermarkedButton
        function CreateSendAudioWatermarkedButtonPushed(app, event)
            %load previous variables send by the fingerprinting app
            load('answer.mat','answer','bestMatchID');
            %create the audiowatermarked file 
            audiowrite('audio_watermarked.wav',app.origwa,app.fs1);
            %prepare and set the variables to send to the decoder as in this case a .mat archive
            N=app.N1;
            Lf=N*app.fs1;
            alpha=app.alpha1;
            n=app.n1;
            idsong=app.idofsong;
            %save the variables
            save('decoder.mat','n','alpha','Lf','answer','bestMatchID','idsong');
        end

        % Value changed function: WithWatermarkCheckBox
        function WithWatermarkCheckBoxValueChanged(app, event)
           %if we check the with watermark box plot the original and watermarked song together
            value = app.WithWatermarkCheckBox.Value;
             endin=length(app.origwa)-1;
             t=0:endin;
             
            if value
              plot(app.UIAxes,t,app.origwa);
               legend(app.UIAxes,'original song','watermarked');
            else
         
           plot(app.UIAxes,t,app.origs,'--b');
            legend(app.UIAxes,'original song');
           end
          
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
          %according to song selection if pressed play the current song  
            if app.OriginalButton.Value
             play(app.playeror);
            end
             if app.WatermarkedButton.Value
             play(app.playerwa);
            end
            
        end

        % Callback function
        function ResumeButtonValueChanged(app, event)

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            %according to song selection if pressed resume the current song    
            if app.OriginalButton.Value
             resume(app.playeror);
            end
             if app.WatermarkedButton.Value
            resume(app.playerwa);
            end
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            %according to song selection if pressed pause the current song  
            if app.OriginalButton.Value
            pause(app.playeror);
            end
             if app.WatermarkedButton.Value
             pause(app.playerwa);
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %according to song selection if pressed stop the current song    
            if app.OriginalButton.Value
             stop(app.playeror);
            end
             if app.WatermarkedButton.Value
            stop(app.playerwa);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signals')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YLim = [-1.2 1.2];
            app.EncoderSSWLabel = uilabel(app.UIFigure);
            app.EncoderSSWLabel.Text = 'Encoder SSW';
            app.WatermarkAudioButton = uibutton(app.UIFigure, 'push');
            app.WatermarkAudioButton.ButtonPushedFcn = createCallbackFcn(app, @WatermarkAudioButtonPushed, true);
            app.WatermarkAudioButton.Text = 'Watermark Audio';
            app.CreateSendAudioWatermarkedButton = uibutton(app.UIFigure, 'push');
            app.CreateSendAudioWatermarkedButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSendAudioWatermarkedButtonPushed, true);
            app.CreateSendAudioWatermarkedButton.Text = 'Create/Send Audio Watermarked';
            app.FrameSizesEditFieldLabel = uilabel(app.UIFigure);
            app.FrameSizesEditFieldLabel.Text = 'Frame Size [s]';
            app.FrameSizesEditField = uieditfield(app.UIFigure, 'text');
            app.WatermarkFactorEditFieldLabel = uilabel(app.UIFigure);
            app.WatermarkFactorEditFieldLabel.Text = 'Watermark Factor';
            app.WatermarkFactorEditField = uieditfield(app.UIFigure, 'text');
            app.bitswatermarkEditFieldLabel = uilabel(app.UIFigure);
            app.bitswatermarkEditFieldLabel.Text = '#bits watermark';
            app.bitswatermarkEditField = uieditfield(app.UIFigure, 'text');
            app.DoneLampLabel = uilabel(app.UIFigure);
            app.DoneLampLabel.Text = 'Done';
            app.DoneLamp = uilamp(app.UIFigure);
            app.WithWatermarkCheckBox = uicheckbox(app.UIFigure);
            app.WithWatermarkCheckBox.ValueChangedFcn = createCallbackFcn(app, @WithWatermarkCheckBoxValueChanged, true);
            app.WithWatermarkCheckBox.Text = 'With Watermark';
            app.ListenButtonGroup = uibuttongroup(app.UIFigure);
            app.ListenButtonGroup.Title = 'Listen';
            app.OriginalButton = uiradiobutton(app.ListenButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.WatermarkedButton = uiradiobutton(app.ListenButtonGroup);
            app.WatermarkedButton.Text = 'Watermarked';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Watermark Sequence')
            xlabel(app.UIAxes2, 'Bit [n]')
            ylabel(app.UIAxes2, 'Value')
            app.SongwatermarkedEditFieldLabel = uilabel(app.UIFigure);
            app.SongwatermarkedEditFieldLabel.Text = 'Song watermarked';
            app.SongwatermarkedEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Encoder

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end